classdef APP_zyx_modified < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        EditField_4  matlab.ui.control.EditField
        Label_4      matlab.ui.control.Label
        EditField_3  matlab.ui.control.EditField
        Label_3      matlab.ui.control.Label
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button_8     matlab.ui.control.RadioButton
        Button_7     matlab.ui.control.RadioButton
        Button_6     matlab.ui.control.RadioButton
        EditField_2  matlab.ui.control.EditField
        Label_2      matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        Label        matlab.ui.control.Label
        Button_5     matlab.ui.control.Button
        Button_4     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        UetButton    matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        origin_image % 原始图像
        processed_img_method1 % 传统图像处理后的图片
        processed_img_method2 % Unet处理后的图片
        unet % unet模型文件
        final_bw_mask1 % method1 的BW mask
        final_bw_mask2 % method2 的BW mask
    end
    
    methods (Access = private)
        
        % 二值MASK 转纯绿色MASK & 细胞计数
        function [rgbMask,numObj] = BW2greenMask(app,BW)
                   d = bwdist(~BW);                  
                   d = imcomplement(d);
                   d = imhmin(d,1);
                            
                   % Calculate the watershed transform of d
                   fluoSep = watershed(d);
                   % imshow(fluoSep,[])
                            
                   % Create RGB Mask
                   fluoSep(~BW) = 0;
                   hts1 = regionprops("table",fluoSep,"MajorAxisLength");
                   numObj = height(hts1);
                            
                   % number_of_labels 
                   n = max(fluoSep(:));          
                   a = 0.00;
                   b = 0.49;
                   c = 0.1;
                   map = [ones(n,1)*a ones(n,1)*b ones(n,1)*c];
                   rgbMask = label2rgb(fluoSep,map,'k');
        end
          
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function Button_3Pushed(app, event)
            if isempty(app.unet)                      
                  msgbox('先载入Unet');
            else
            sampleSize = 256;
            originalPicture =  rgb2gray(app.origin_image);
            [oriRow,oriCol] = size(originalPicture);
            rowNumber = fix(oriRow/sampleSize);
            colNumber = fix(oriCol/sampleSize);
            for rowCount = 1:rowNumber+1
                for colCount = 1:colNumber+1
                       if colCount == colNumber+1 && rowCount == rowNumber+1
                            samplePicutre = originalPicture(oriRow-sampleSize+1:oriRow,oriCol-sampleSize+1:oriCol);
                            patch = semanticseg(samplePicutre, app.unet);
                            finalPatch(oriRow-sampleSize+1:oriRow,oriCol-sampleSize+1:oriCol) = patch;
                       elseif colCount == colNumber+1
                              samplePicutre = originalPicture((sampleSize*(rowCount-1)+1):sampleSize*(rowCount),oriCol-sampleSize+1:oriCol);
                              patch = semanticseg(samplePicutre, app.unet);
                              finalPatch((sampleSize*(rowCount-1)+1):sampleSize*(rowCount),oriCol-sampleSize+1:oriCol) = patch;
                       elseif rowCount == rowNumber+1
                              samplePicutre = originalPicture(oriRow-sampleSize+1:oriRow,(sampleSize*(colCount-1)+1):sampleSize*(colCount));
                              patch = semanticseg(samplePicutre, app.unet);
                              finalPatch(oriRow-sampleSize+1:oriRow,(sampleSize*(colCount-1)+1):sampleSize*(colCount)) = patch;
                        else
                            samplePicutre = originalPicture((sampleSize*(rowCount-1)+1):sampleSize*(rowCount),(sampleSize*(colCount-1)+1):sampleSize*(colCount));
                            patch = semanticseg(samplePicutre, app.unet);
                            finalPatch((sampleSize*(rowCount-1)+1):sampleSize*(rowCount),(sampleSize*(colCount-1)+1):sampleSize*(colCount)) = patch; 
                      end
                end
            end

            end
     
    
            finalMaskBW = double(finalPatch)-2;
            finalMaskBW = abs(finalMaskBW);
            finalMaskBW = imfill(finalMaskBW,"holes");
            finalMaskBW = bwareaopen(finalMaskBW,2200); % 移除面积小于2500的
            app.final_bw_mask2 = finalMaskBW;

            
            [greenMask, numObj] = BW2greenMask(app,finalMaskBW);
            % 输出展示细胞数量
            app.EditField_2.Value =  num2str(numObj);
            app.EditField_4.Value =  "图像处理完成";
            imshow( greenMask,'Parent',app.UIAxes);

            app.processed_img_method2 = greenMask;
                              
        end

        % Button pushed function: Button_3
        function Button_2Pushed(app, event)
            % 执行传统图像处理算法
            
            fluoGray = rgb2gray(app.origin_image);
            % app.EditField_3.Value =  "处理中";
            nHood = true(13);
            fluoSTD = stdfilt(fluoGray,nHood);
            fluoSTD = rescale(fluoSTD);
          
            fluoBW = imbinarize(fluoSTD); 
            fluoBW = bwareaopen(fluoBW,2500);              
            % Try : Growing Segmentations with Active Contours
            % -1 1 越接近 1 背景越强
            fluoBW = activecontour(fluoGray,fluoBW,10,...
            "Chan-Vese","ContractionBias",-0.02,...
            "SmoothFactor",0.1);
            fluoBW = imfill(fluoBW,"holes"); 
            
            SE = strel('disk',16);
            fluoBW = imclose(fluoBW,SE);
            fluoBW = imfill(fluoBW,"holes");
            SE2 = strel('disk',18);
            fluoBW = imopen(fluoBW,SE2);
            app.final_bw_mask1 = fluoBW;
            [greenMask,numObj] = BW2greenMask(app,fluoBW);
            app.EditField.Value = num2str(numObj);
            app.EditField_3.Value =  "图像处理完成";
            imshow( greenMask,'Parent',app.UIAxes);
            title(app.UIAxes,"传统图像处理后的图片")
            
            app.processed_img_method1 = greenMask;
                               
           
        end

        % Button pushed function: Button_5
        function ButtonPushed(app, event)
            % 载入图像
            [fliename, pathname] = uigetfile({'*.jpg';'*.tif';'*.png'},"Open file");
            fullpathname = strcat(pathname,fliename);
            app.origin_image = imread(fullpathname);
            imshow(app.origin_image,'Parent',app.UIAxes);
            title(app.UIAxes,"原始图像");
        end

        % Button pushed function: UetButton
        function UetButtonPushed(app, event)
            % 载入unet
            [fliename pathname] = uigetfile({'*.mat'},"Open file");
            fullpathname = strcat(pathname,fliename);
            data = load(fullpathname);
            app.unet = data.net;
            % lgraph = layerGraph(unet);
            % plot(lgraph);
            % title("网络结构")
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
           % 保存 传统图像处理结果
           imwrite(app.processed_img_method1,'Method1 处理后的图像.png');
        end

        % Button pushed function: Button_2
        function Button_5Pushed(app, event)
           % 保存 Unet图像处理结果
           imwrite(app.processed_img_method2,'Method2 处理后的图像.png');
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
 
            changingValue = event.Value;
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % 群按钮
            switch  app.ButtonGroup.SelectedObject
                case app.Button_8
                    if isempty(app.final_bw_mask2)
                        imshow(app.origin_image,'Parent',app.UIAxes);
                        msgbox('先执行深度学习处理方法');
                    else
                    finalPicture = labeloverlay(app.origin_image,app.final_bw_mask2);
                    imshow(finalPicture,'Parent',app.UIAxes);
                    title(app.UIAxes,"原始图像&深度学习处理后的图像");
                    end
       
                case app.Button_7
                    if isempty(app.final_bw_mask1)
                        imshow(app.origin_image,'Parent',app.UIAxes);
                        msgbox('先执行传统图像处理方法');
                    else
                    finalPicture = labeloverlay(app.origin_image,app.final_bw_mask1);
                    imshow(finalPicture,'Parent',app.UIAxes);
                    title(app.UIAxes,"原始图像&传统方法处理后的图像");
                    end
                case app.Button_6
                    if isempty(app.origin_image)
                        imshow(app.origin_image,'Parent',app.UIAxes);
                        msgbox('请先载入图片');
                    else

                    imshow(app.origin_image,'Parent',app.UIAxes);
                    title(app.UIAxes,"原始图像");
                    end
                otherwise
                imshow(app.origin_image,'Parent',app.UIAxes);
                title(app.UIAxes,"原始图像");
            

            end
        end

        % Value changing function: EditField_3
        function EditField_3ValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '细胞图像分割';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始图像')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UetButton = uibutton(app.UIFigure, 'push');
            app.UetButton.ButtonPushedFcn = createCallbackFcn(app, @UetButtonPushed, true);
            app.UetButton.Text = '载入Uet 模型';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button.Text = '执行深度学习技术处理方法';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_2.Text = '图像保存';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_3.Text = '执行传统数字图像处理方法';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '图像保存';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_5.Text = '载入图像';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '细胞个数';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '细胞个数';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '显示图像选择';
            app.Button_6 = uiradiobutton(app.ButtonGroup);
            app.Button_6.Text = '原始图像';
            app.Button_6.Value = true;
            app.Button_7 = uiradiobutton(app.ButtonGroup);
            app.Button_7.Text = '原始图像&传统方法处理后的图像';
            app.Button_8 = uiradiobutton(app.ButtonGroup);
            app.Button_8.Text = '原始图像&深度学习处理后的图像';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '处理状态';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.ValueChangingFcn = createCallbackFcn(app, @EditField_3ValueChanging, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '处理状态';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_zyx_modified

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end